classdef App_Help < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DYNAblastHelpUIFigure  matlab.ui.Figure
        InformationPanel       matlab.ui.container.Panel
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            fig = app.InformationPanel;
            txa = uitextarea(fig,...
                'Value', {'INFORMATION'; ' ';...
                'All peaces of information about the software, how to use input data output data code metodology general analysis advanced analysis and others explanations you can go to Github and read all about Dynablast in:';...
                ' ';'https://github.com/AnaWaldila/dynablast'; ' '; ' '; 'REFERENCES'; ' '; ...
                'All methodology and mathematical apreciation, you can find in Master Thesis: '; ...
                'REIS, Ana Waldila de Queiroz Ramiro. Dynamic analysis of plates submitted to blast load. 2019. 164f. Master s thesis (Master of Science in Civil Engineering) - Engineering Faculty, Rio de Janeiro State University, Rio de Janeiro, 2019.'; ...
                ' '; 'You can see this thesis in this link:'; 'http://www.labbas.eng.uerj.br/pgeciv/nova/files/dissertacoes/179.pdf'});
            txa.Editable = 'off';
            txa.Position = [1 1 450 330];
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DYNAblastHelpUIFigure = uifigure('Visible', 'off');
            app.DYNAblastHelpUIFigure.Name = 'DYNAblast - Help';
            app.InformationPanel = uipanel(app.DYNAblastHelpUIFigure);
            app.InformationPanel.Title = 'Information';
            % Show the figure after all components are created
            app.DYNAblastHelpUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = App_Help

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DYNAblastHelpUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DYNAblastHelpUIFigure)
        end
    end
end